classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure         matlab.ui.Figure
        GridLayout       matlab.ui.container.GridLayout
        LeftPanel        matlab.ui.container.Panel
        UITable          matlab.ui.control.Table
        Label            matlab.ui.control.Label
        Button_init      matlab.ui.control.Button
        Button_mainPlot  matlab.ui.control.Button
        Label_2          matlab.ui.control.Label
        samplingRate     matlab.ui.control.NumericEditField
        CenterPanel      matlab.ui.container.Panel
        Panel            matlab.ui.container.Panel
        EditFieldLabel   matlab.ui.control.Label
        EditField        matlab.ui.control.EditField
        UIAxes_main      matlab.ui.control.UIAxes
        UIAxes2          matlab.ui.control.UIAxes
        RightPanel       matlab.ui.container.Panel
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
        twoPanelWidth = 768;
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            a = [10,2,0;
                20,1,pi/2;
                30,4,0];
            app.UITable.Data  = a;
            Button_mainPlotPushed(app)
        end

        % Button pushed function: Button_init
        function Button_initPushed(app, event)
            startupFcn(app);
        end

        % Button pushed function: Button_mainPlot
        function Button_mainPlotPushed(app, event)
            t = 5;%时间长度
            Fs = app.samplingRate.Value;
            D = app.UITable.Data;
            m =size(D,1);
            x = linspace(0,t,round(Fs*t));
            y = D(1,2)*sin(2*pi*D(1,1)*x+D(1,3));
            if m>1
                for i = 2:m
                    y = y + D(i,2)*sin(2*pi*D(i,1)*x+D(i,3));
                end
            end
            plot(app.UIAxes_main,x,y);
        end

        % Value changed function: samplingRate
        function samplingRateValueChanged(app, event)
            v = app.samplingRate.Value;
            max = 1000000;
            min = 100;
            if v > max
                v = max;
            end
            if v < min
                v = min;
            end
            Fs = round(v) ;
            
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 3x1 grid
                app.GridLayout.RowHeight = {811, 811, 811};
                app.GridLayout.ColumnWidth = {'1x'};
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = 1;
                app.LeftPanel.Layout.Row = 2;
                app.LeftPanel.Layout.Column = 1;
                app.RightPanel.Layout.Row = 3;
                app.RightPanel.Layout.Column = 1;
            elseif (currentFigureWidth > app.onePanelWidth && currentFigureWidth <= app.twoPanelWidth)
                % Change to a 2x2 grid
                app.GridLayout.RowHeight = {811, 811};
                app.GridLayout.ColumnWidth = {'1x', '1x'};
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = [1,2];
                app.LeftPanel.Layout.Row = 2;
                app.LeftPanel.Layout.Column = 1;
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 2;
            else
                % Change to a 1x3 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {326, '1x', 172};
                app.LeftPanel.Layout.Row = 1;
                app.LeftPanel.Layout.Column = 1;
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = 2;
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 3;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.UITable = uitable(app.LeftPanel);
            app.UITable.ColumnName = {'频率(Hz)'; '幅值'; '初相位(°)'};
            app.UITable.RowName = {'1'; '2'; '3'};
            app.UITable.ColumnEditable = true;
            app.Label = uilabel(app.LeftPanel);
            app.Label.Text = '输入信号信息';
            app.Button_init = uibutton(app.LeftPanel, 'push');
            app.Button_init.ButtonPushedFcn = createCallbackFcn(app, @Button_initPushed, true);
            app.Button_init.Text = '初始化';
            app.Button_mainPlot = uibutton(app.LeftPanel, 'push');
            app.Button_mainPlot.ButtonPushedFcn = createCallbackFcn(app, @Button_mainPlotPushed, true);
            app.Button_mainPlot.Text = '绘制图像';
            app.Label_2 = uilabel(app.LeftPanel);
            app.Label_2.Text = '采样频率';
            app.samplingRate = uieditfield(app.LeftPanel, 'numeric');
            app.samplingRate.RoundFractionalValues = 'on';
            app.samplingRate.ValueChangedFcn = createCallbackFcn(app, @samplingRateValueChanged, true);
            app.samplingRate.Value = 500;
            app.CenterPanel = uipanel(app.GridLayout);
            app.Panel = uipanel(app.CenterPanel);
            app.Panel.Title = 'Panel';
            app.EditFieldLabel = uilabel(app.Panel);
            app.EditFieldLabel.Text = 'Edit Field';
            app.EditField = uieditfield(app.Panel, 'text');
            app.EditField.Editable = 'off';
            app.UIAxes_main = uiaxes(app.CenterPanel);
            title(app.UIAxes_main, '信号输出')
            xlabel(app.UIAxes_main, 'X')
            ylabel(app.UIAxes_main, 'Y')
            app.UIAxes2 = uiaxes(app.CenterPanel);
            title(app.UIAxes2, '自相关')
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            app.RightPanel = uipanel(app.GridLayout);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end